classdef Game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Switch           matlab.ui.control.Switch
        RedPiece1        matlab.ui.control.Image
        GreenPiece1      matlab.ui.control.Image
        Dice4_2          matlab.ui.control.Image
        Dice3_2          matlab.ui.control.Image
        Dice2_2          matlab.ui.control.Image
        Dice1_2          matlab.ui.control.Image
        Dice1            matlab.ui.control.Image
        Dice3            matlab.ui.control.Image
        YellowPiece3     matlab.ui.control.Image
        BluePiece1       matlab.ui.control.Image
        RedPiece3        matlab.ui.control.Image
        RedPiece2        matlab.ui.control.Image
        GreenPiece3      matlab.ui.control.Image
        GreenPiece2      matlab.ui.control.Image
        YellowPiece2     matlab.ui.control.Image
        YellowPiece1     matlab.ui.control.Image
        BluePiece2       matlab.ui.control.Image
        BluePiece3       matlab.ui.control.Image
        PlayerNameLabel  matlab.ui.control.Label
        PlayerTurnLabel  matlab.ui.control.Label
        RollDiceButton   matlab.ui.control.Button
        Dice4            matlab.ui.control.Image
        Dice2            matlab.ui.control.Image
        GameBoard        matlab.ui.control.Image
    end

    
    properties (Access = private)
        ActivePlayers = [];
        % Each row corresponds to a player
        MovementDirection = ["down", "down", "down"; "left", "left", "left"; ...
            "up", "up", "up"; "right", "right", "right"];        

        PlayerTurnCounter = 1;
        
        % Corner Space Coordinates
        TopRightCornerSpace = [491,384,34,33];
        BottomRightCornerSpace = [491,27,34,33];
        BottomLeftCornerSpace = [119,27,34,33];
        TopLeftCornerSpace = [119,384,34,33];
        % Coordinates of all the square starting from the top right going
        % down
        SquareLocations = [491,384,34,33; 491,335,34,33; 491,282,34,33; ...
            491,232,34,33; 491,185,34,33; 491,133,34,33; 491,79,34,33; ...
            491,27,34,33; 443,27,34,33; 392,27,34,33; 338,27,34,33; ...
            285,27,34,33; 228,27,34,33; 172,27,34,33; 119,27,34,33; ...
            119,79,34,33; 119,133,34,33; 119,180,34,33; 119,233,34,33; ...
            119,282,34,33; 119,332,34,33; 119,384,34,33; 172,384,34,33; ...
            228,384,34,33; 285,384,34,33; 338,384,34,33; 392,384,34,33; ...
            441,384,34,33];
        
        % Current index of each player
        % Each space on the board is indexed where the top right corner is
        % index 1. The space bellow that is 2, below that is 3, etc, all
        % the way to idnex 28 (which is the space to the left of the top
        % right corner)
        Blue1CurrentPositionIndex; Blue2CurrentPositionIndex; Blue3CurrentPositionIndex;
        Yellow1CurrentPositionIndex; Yellow2CurrentPositionIndex; Yellow3CurrentPositionIndex;
        Green1CurrentPositionIndex; Green2CurrentPositionIndex; Green3CurrentPositionIndex
        Red1CurrentPositionIndex; Red2CurrentPositionIndex; Red3CurrentPositionIndex;
        
        % Coordinates of spaces directly below their home squares
        BlueHomePivot = [491,282,34,33]; YellowHomePivot = [392,27,34,33];
        GreenHomePivot = [119,133,34,33]; RedHomePivot = [228,384,34,33]; 
        
        % Coordinates of piece final destinations (in the big circles)
        BlueHomeBase = [340,282,34,33]; YellowHomeBase = [392,182,34,33];
        GreenHomeBase = [286,133,34,33]; RedHomeBase = [228,237,34,33];
        
        % Coordinates of spaces in between the HomeBase and HomePivot
        BlueHomeCoordinates = [443,282,34,33; 396,282,34,33];
        YellowHomeCoordinates = [392,79,34,33; 392,126,34,33];
        GreenHomeCoordinates = [172,133,34,33; 225,133,34,33];
        RedHomeCoordinates = [228,331,34,33; 228,289,34,33];
        
        DiceOutput = 0;
    end
    
    properties (Access = public)
        StartApp    % Contains variables in Start.mlapp
        % -1 represents players that are not active
        PiecesReachedHome = [-1, -1, -1, -1];
    end
    
    methods (Access = private)
        
        function set_starting_pieces (app)
            % Sets blue player pieces
            if is_a_player(app, "blue")
                app.BluePiece1.Position = [443,232,34,33];
                app.BluePiece1.Enable = 'off';
                app.BluePiece2.Position = [443,232,34,33];
                app.BluePiece2.Enable = 'off';
                app.BluePiece3.Position = [443,232,34,33];
                app.BluePiece3.Enable = 'off';                
            else
                app.BluePiece1.Enable = 'off';
                app.BluePiece1.Visible = 'off';
                app.BluePiece2.Enable = 'off';
                app.BluePiece2.Visible = 'off';
                app.BluePiece3.Enable = 'off';
                app.BluePiece3.Visible = 'off';
            end
            % Sets yellow player pieces
            if is_a_player(app, "yellow")
                app.YellowPiece1.Position = [337,76,34,33];
                app.YellowPiece1.Enable = 'off';
                app.YellowPiece2.Position = [337,76,34,33];
                app.YellowPiece2.Enable = 'off';
                app.YellowPiece3.Position = [337,76,34,33];
                app.YellowPiece3.Enable = 'off';               
            else
                app.YellowPiece1.Enable = 'off';
                app.YellowPiece1.Visible = 'off';
                app.YellowPiece2.Enable = 'off';
                app.YellowPiece2.Visible = 'off';
                app.YellowPiece3.Enable = 'off';
                app.YellowPiece3.Visible = 'off';
            end
            % Sets green player pieces
            if is_a_player(app, "green")
                app.GreenPiece1.Position = [169,180,34,33];
                app.GreenPiece1.Enable = 'off';
                app.GreenPiece2.Position = [169,180,34,33];
                app.GreenPiece2.Enable = 'off';
                app.GreenPiece3.Position = [169,180,34,33];
                app.GreenPiece3.Enable = 'off';               
            else
                app.GreenPiece1.Enable = 'off';
                app.GreenPiece1.Visible = 'off';
                app.GreenPiece2.Enable = 'off';
                app.GreenPiece2.Visible = 'off';
                app.GreenPiece3.Enable = 'off';
                app.GreenPiece3.Visible = 'off';
            end
            % Sets red player pieces
            if is_a_player(app, "red")
                 app.RedPiece1.Position = [285,330,34,33]; 
                app.RedPiece1.Enable = 'off';
                app.RedPiece2.Position = [285,330,34,33];
                app.RedPiece2.Enable = 'off';
                app.RedPiece3.Position = [285,330,34,33];
                app.RedPiece3.Enable = 'off';               
            else
                app.RedPiece1.Enable = 'off';
                app.RedPiece1.Visible = 'off';
                app.RedPiece2.Enable = 'off';
                app.RedPiece2.Visible = 'off';
                app.RedPiece3.Enable = 'off';
                app.RedPiece3.Visible = 'off';
            end
        end        
        
        function players = set_players (app)
            arrayOfPlayers = [];
            
            % Checks if players exist and have names
            if length(app.StartApp.BluePlayerName) == length('Blue Player Name')                 
                bluePlayerHasName = ~strcmp(app.StartApp.BluePlayerName, 'Blue Player Name');
            else
                bluePlayerHasName = true;
            end
            if length(app.StartApp.YellowPlayerName) == length('Yellow Player Name')
                yellowPlayerHasName = ~strcmp(app.StartApp.YellowPlayerName, 'Yellow Player Name');
            else
                yellowPlayerHasName = true;
            end
            if length(app.StartApp.GreenPlayerName) == length('Green Player Name')
                greenPlayerHasName = ~strcmp(app.StartApp.GreenPlayerName, 'Green Player Name');
            else
                greenPlayerHasName = true;
            end
            if length(app.StartApp.RedPlayerName) == length('Red Player Name')
                redPlayerHasName = ~strcmp(app.StartApp.RedPlayerName, 'Red Player Name');
            else
                redPlayerHasName = true;
            end                                             
            
            % Sets the PiecesReachedHome array to 0, indicated that the
            % player is active
            if bluePlayerHasName
                arrayOfPlayers = [arrayOfPlayers {"blue"}];
                app.PiecesReachedHome(1) = 0;
            end
            if yellowPlayerHasName
                arrayOfPlayers = [arrayOfPlayers {"yellow"}];
                app.PiecesReachedHome(2) = 0;
            end
            if greenPlayerHasName
                arrayOfPlayers = [arrayOfPlayers {"green"}];
                app.PiecesReachedHome(3) = 0;
            end
            if redPlayerHasName
                arrayOfPlayers = [arrayOfPlayers {"red"}];
                app.PiecesReachedHome(4) = 0;
            end
                
            players = arrayOfPlayers;            
        end
        
        function isActive = is_a_player (app, playerType)
            % A player is active when their name does not equal the default
            % text in the name boxes
            for i = 1 : length(app.ActivePlayers)
                if strcmp(playerType, string(app.ActivePlayers(i)))
                    isActive = true;
                    return;
                end
            end
            
            isActive = false;
        end
        
        function is_game_over (app)
            % Checks if a player has 3 pieces at their home base
            for i = 1 : length(app.PiecesReachedHome)
                if app.PiecesReachedHome(i) == 3
                    disable_players(app);
                    app.RollDiceButton.Enable = 'off';
                    WinningScreen(app)
                end
            end
        end

        
        function disable_players(app)
            % Disables all players
            app.BluePiece1.Enable = 'off';
            app.BluePiece2.Enable = 'off';
            app.BluePiece3.Enable = 'off';
            
            app.YellowPiece1.Enable = 'off';
            app.YellowPiece2.Enable = 'off';
            app.YellowPiece3.Enable = 'off';
            
            app.GreenPiece1.Enable = 'off';
            app.GreenPiece2.Enable = 'off';
            app.GreenPiece2.Enable = 'off';
            
            app.RedPiece1.Enable = 'off';
            app.RedPiece2.Enable = 'off';
            app.RedPiece2.Enable = 'off'; 
        end       
        
        function hide_dice (app)
            % Hides all dice except for Dice1 and Dice4_2 so 2 dice always
            % display on the screen
            app.Dice2.Visible = 'off';
            app.Dice3.Visible = 'off';
            app.Dice4.Visible = 'off';
            
            app.Dice1_2.Visible = 'off';
            app.Dice2_2.Visible = 'off';
            app.Dice3_2.Visible = 'off';
        end
        
        function set_starting_name_label (app)
            % Changes PlayerNameLabel to color of current player turn
            if strcmp(string(app.ActivePlayers(1)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            elseif strcmp(string(app.ActivePlayers(1)), "yellow")
                app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
                app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            elseif strcmp(string(app.ActivePlayers(1)), "green")
                app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
                app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            else
                app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
                app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end
        
        function isHome = is_home(app, playerColor, pieceNumber)
            % Checks if a blue player piece is at home
            if strcmp(playerColor, "blue")                               
                switch pieceNumber
                    case 1
                        atHome = app.BluePiece1.Position == app.BlueHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    case 2
                        atHome = app.BluePiece2.Position == app.BlueHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    otherwise
                        atHome = app.BluePiece3.Position == app.BlueHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                end
            % Checks if a yellow player piece is at home
            elseif strcmp(playerColor, "yellow")
                switch pieceNumber
                    case 1
                        atHome = app.YellowPiece1.Position == app.YellowHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    case 2
                        atHome = app.YellowPiece2.Position == app.YellowHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    otherwise
                        atHome = app.YellowPiece3.Position == app.YellowHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                end
            % Checks if a green player piece is at home    
            elseif strcmp(playerColor, "green")
                switch pieceNumber
                    case 1
                        atHome = app.GreenPiece1.Position == app.GreenHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    case 2
                        atHome = app.GreenPiece2.Position == app.GreenHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    otherwise
                        atHome = app.GreenPiece3.Position == app.GreenHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                end
            % Checks if a red player piece is at home    
            else
                switch pieceNumber
                    case 1
                        atHome = app.RedPiece1.Position == app.RedHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    case 2
                        atHome = app.RedPiece2.Position == app.RedHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                    otherwise
                        atHome = app.RedPiece3.Position == app.RedHomeBase;
                        if all(atHome)
                            isHome = true;
                        else
                            isHome = false;
                        end
                end
            end            
        end
        
        function move_player_pieces(app)
            % Starting animation that plays to move 1 of each active
            % player's piece onto the board
            disable_players(app);
            app.RollDiceButton.Enable = 'off';
            pause(1);
            
            if is_a_player(app, "blue")
                destination = app.BluePiece1.Position(1) + 48;
                coordinates = linspace(app.BluePiece1.Position(1), destination, 100);
                for i = 1 : length(coordinates)
                    app.BluePiece1.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Blue1CurrentPositionIndex = 4;
                app.BluePiece1.Enable = 'on';
                pause(0.5);
                app.BluePiece1.Enable = 'off';
            end
            
            if is_a_player(app, "yellow")
                destination = app.YellowPiece1.Position(2) - 49;
                coordinates = linspace(app.YellowPiece1.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.YellowPiece1.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Yellow1CurrentPositionIndex = 11;
                app.YellowPiece1.Enable = 'on';
                pause(0.5);
                app.YellowPiece1.Enable = 'off';
            end
            
            if is_a_player(app, "green")
                destination = [119,180,34,33];
                coordinates = linspace(app.GreenPiece1.Position(1), destination(1), 100);
                for i = 1 : length(coordinates)
                    app.GreenPiece1.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Green1CurrentPositionIndex = 18;
                app.MovementDirection(3, 1) = "up";
                app.GreenPiece1.Enable = 'on';
                pause(0.5);
                app.GreenPiece1.Enable = 'off';
            end
            
            if is_a_player(app, "red")
                destination = app.SquareLocations(25, 2);
                coordinates = linspace(app.RedPiece1.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.RedPiece1.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Red1CurrentPositionIndex = 25;
                app.MovementDirection(4, 1) = "right";
                app.RedPiece1.Enable = 'on';
                pause(0.5);
                app.RedPiece1.Enable = 'off';                              
            end
            
            app.RollDiceButton.Enable = 'on';
        end
        
        function move_backwards(app, playerColor, pieceNumber)
            % Moves player piece backward if they land on an arrow
            % Code for all players (colors) and pieces is relatively the same
            if strcmp(playerColor, "blue")
                if pieceNumber == 1
                    % Compares piece location with location of each arrow
                    matchesFirstLocation = app.BluePiece1.Position(1:2) == [491,79];
                    matchesSecondLocation = app.BluePiece1.Position(1:2) == [172,27];
                    matchesThirdLocation = app.BluePiece1.Position(1:2) == [119,282];
                    matchesFourthLocation = app.BluePiece1.Position(1:2) == [392,384];
                    
                    % Checks if piece is at first arrow location
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.BluePiece1.Enable = 'off';
                            pause(0.5);
                            app.BluePiece1.Enable = 'on';
                            pause(0.5);
                        end
                        % Generates coordinates from end of arrow to
                        % beginning of arrow (creating animation)
                        coordinates = linspace(app.BluePiece1.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = 4;
                    % Checks if piece is at second arrow location
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.BluePiece1.Enable = 'off';
                            pause(0.5);
                            app.BluePiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece1.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = 12;
                    % Checks if piece is at third arrow location
                    elseif all(matchesThirdLocation)
                        for i = 1 :3
                            app.BluePiece1.Enable = 'off';
                            pause(0.5);
                            app.BluePiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece1.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = 18;
                    % Checks if piece is at last arrow location
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.BluePiece1.Enable = 'off';
                            pause(0.5);
                            app.BluePiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece1.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = 26;
                    else
                        return;
                    end                                       
                elseif pieceNumber == 2
                    matchesFirstLocation = app.BluePiece2.Position(1:2) == [491,79];
                    matchesSecondLocation = app.BluePiece2.Position(1:2) == [172,27];
                    matchesThirdLocation = app.BluePiece2.Position(1:2) == [119,282];
                    matchesFourthLocation = app.BluePiece2.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.BluePiece2.Enable = 'off';
                            pause(0.5);
                            app.BluePiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece2.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.BluePiece2.Enable = 'off';
                            pause(0.5);
                            app.BluePiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece2.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.BluePiece2.Enable = 'off';
                            pause(0.5);
                            app.BluePiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece2.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.BluePiece2.Enable = 'off';
                            pause(0.5);
                            app.BluePiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece2.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = 26;
                    else
                        return;
                    end
                else
                    matchesFirstLocation = app.BluePiece3.Position(1:2) == [491,79];
                    matchesSecondLocation = app.BluePiece3.Position(1:2) == [172,27];
                    matchesThirdLocation = app.BluePiece3.Position(1:2) == [119,282];
                    matchesFourthLocation = app.BluePiece3.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.BluePiece3.Enable = 'off';
                            pause(0.5);
                            app.BluePiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece3.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.BluePiece3.Enable = 'off';
                            pause(0.5);
                            app.BluePiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece3.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.BluePiece3.Enable = 'off';
                            pause(0.5);
                            app.BluePiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece3.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.BluePiece3.Enable = 'off';
                            pause(0.5);
                            app.BluePiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.BluePiece3.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = 26;
                    else
                        return;
                    end
                end
            elseif strcmp(playerColor, "yellow")
                if pieceNumber == 1
                    matchesFirstLocation = app.YellowPiece1.Position(1:2) == [491,79];
                    matchesSecondLocation = app.YellowPiece1.Position(1:2) == [172,27];
                    matchesThirdLocation = app.YellowPiece1.Position(1:2) == [119,282];
                    matchesFourthLocation = app.YellowPiece1.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.YellowPiece1.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece1.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.YellowPiece1.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece1.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.YellowPiece1.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece1.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.YellowPiece1.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece1.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = 26;
                    else
                        return;
                    end
                elseif pieceNumber == 2
                    matchesFirstLocation = app.YellowPiece2.Position(1:2) == [491,79];
                    matchesSecondLocation = app.YellowPiece2.Position(1:2) == [172,27];
                    matchesThirdLocation = app.YellowPiece2.Position(1:2) == [119,282];
                    matchesFourthLocation = app.YellowPiece2.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.YellowPiece2.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece2.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.YellowPiece2.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece2.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.YellowPiece2.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece2.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.YellowPiece2.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece2.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = 26;
                    else
                        return;
                    end
                else
                    matchesFirstLocation = app.YellowPiece3.Position(1:2) == [491,79];
                    matchesSecondLocation = app.YellowPiece3.Position(1:2) == [172,27];
                    matchesThirdLocation = app.YellowPiece3.Position(1:2) == [119,282];
                    matchesFourthLocation = app.YellowPiece3.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.YellowPiece3.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece3.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.YellowPiece3.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece3.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.YellowPiece3.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece3.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.YellowPiece3.Enable = 'off';
                            pause(0.5);
                            app.YellowPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.YellowPiece3.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = 26;
                    else
                        return;
                    end
                end
            elseif strcmp(playerColor, "green")
                if pieceNumber == 1
                    matchesFirstLocation = app.GreenPiece1.Position(1:2) == [491,79];
                    matchesSecondLocation = app.GreenPiece1.Position(1:2) == [172,27];
                    matchesThirdLocation = app.GreenPiece1.Position(1:2) == [119,282];
                    matchesFourthLocation = app.GreenPiece1.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.GreenPiece1.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece1.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.GreenPiece1.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece1.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.GreenPiece1.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece1.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.GreenPiece1.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece1.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = 26;
                    else
                        return;
                    end
                elseif pieceNumber == 2
                    matchesFirstLocation = app.GreenPiece2.Position(1:2) == [491,79];
                    matchesSecondLocation = app.GreenPiece2.Position(1:2) == [172,27];
                    matchesThirdLocation = app.GreenPiece2.Position(1:2) == [119,282];
                    matchesFourthLocation = app.GreenPiece2.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.GreenPiece2.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece2.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.GreenPiece2.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece2.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.GreenPiece2.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece2.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.GreenPiece2.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece2.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = 26;
                    else
                        return;
                    end
                else
                    matchesFirstLocation = app.GreenPiece3.Position(1:2) == [491,79];
                    matchesSecondLocation = app.GreenPiece3.Position(1:2) == [172,27];
                    matchesThirdLocation = app.GreenPiece3.Position(1:2)== [119,282];
                    matchesFourthLocation = app.GreenPiece3.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.GreenPiece3.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece3.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.GreenPiece3.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece3.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.GreenPiece3.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece3.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.GreenPiece3.Enable = 'off';
                            pause(0.5);
                            app.GreenPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.GreenPiece3.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = 26;
                    else
                        return;
                    end
                end
            else
                if pieceNumber == 1
                    matchesFirstLocation = app.RedPiece1.Position(1:2) == [491,79];
                    matchesSecondLocation = app.RedPiece1.Position(1:2) == [172,27];
                    matchesThirdLocation = app.RedPiece1.Position(1:2)== [119,282];
                    matchesFourthLocation = app.RedPiece1.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.RedPiece1.Enable = 'off';
                            pause(0.5);
                            app.RedPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece1.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.RedPiece1.Enable = 'off';
                            pause(0.5);
                            app.RedPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece1.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.RedPiece1.Enable = 'off';
                            pause(0.5);
                            app.RedPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece1.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.RedPiece1.Enable = 'off';
                            pause(0.5);
                            app.RedPiece1.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece1.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = 26;
                    else
                        return;
                    end
                elseif pieceNumber == 2
                    matchesFirstLocation = app.RedPiece2.Position(1:2) == [491,79];
                    matchesSecondLocation = app.RedPiece2.Position(1:2) == [172,27];
                    matchesThirdLocation = app.RedPiece2.Position(1:2) == [119,282];
                    matchesFourthLocation = app.RedPiece2.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.RedPiece2.Enable = 'off';
                            pause(0.5);
                            app.RedPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece2.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.RedPiece2.Enable = 'off';
                            pause(0.5);
                            app.RedPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece2.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.RedPiece2.Enable = 'off';
                            pause(0.5);
                            app.RedPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece2.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.RedPiece2.Enable = 'off';
                            pause(0.5);
                            app.RedPiece2.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece2.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = 26;
                    else
                        return;
                    end
                else
                    matchesFirstLocation = app.RedPiece3.Position(1:2) == [491,79];
                    matchesSecondLocation = app.RedPiece3.Position(1:2) == [172,27];
                    matchesThirdLocation = app.RedPiece3.Position(1:2) == [119,282];
                    matchesFourthLocation = app.RedPiece3.Position(1:2) == [392,384];
                    
                    if all(matchesFirstLocation)
                        for i = 1 : 3
                            app.RedPiece3.Enable = 'off';
                            pause(0.5);
                            app.RedPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece3.Position(2), app.SquareLocations(4, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = 4;
                    elseif all(matchesSecondLocation)
                        for i = 1 : 3
                            app.RedPiece3.Enable = 'off';
                            pause(0.5);
                            app.RedPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece3.Position(1), app.SquareLocations(12, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = 12;
                    elseif all(matchesThirdLocation)
                        for i = 1 : 3
                            app.RedPiece3.Enable = 'off';
                            pause(0.5);
                            app.RedPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece3.Position(2), app.SquareLocations(18, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = 18;
                    elseif all(matchesFourthLocation)
                        for i = 1 : 3
                            app.RedPiece3.Enable = 'off';
                            pause(0.5);
                            app.RedPiece3.Enable = 'on';
                            pause(0.5);
                        end
                        coordinates = linspace(app.RedPiece3.Position(1), app.SquareLocations(26, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = 26;
                    else
                        return;
                    end
                end
            end
        end        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            % Set StartApp property = to the properties of Start.mlapp
            app.StartApp = CallingApp;  
            
            % Disables Critical Stage switch
            app.Switch.Visible = 'off';
            app.Switch.Enable = 'off';
            
            % Set up board according to active players
            app.ActivePlayers = set_players(app);            
            set_starting_name_label(app);
            set_starting_pieces(app);
            hide_dice(app);
            
            % Play animation to move 1 piece of each active player onto the
            % board
            move_player_pieces(app);
        end

        % Image clicked function: BluePiece1
        function BluePiece1ImageClicked(app, event)
            % Disable pieces that were not clicked and roll dice button
            app.BluePiece2.Enable = 'off';
            app.BluePiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            % Checks if piece is located at key positions
            atStart = app.BluePiece1.Position == [443,232,34,33];
            hitCorner = false; % If player has to change directions (they hit a corner)
            turnHome = false; % If player has to change directions to their HomeBase
            
            if all(atStart)
                destination = app.BluePiece1.Position(1) + 48; % Move to starting location on board
                % Generate coordinates from spawn to starting location
                coordinates = linspace(app.BluePiece1.Position(1), destination, 100);
                % Play animation
                for i = 1 : length(coordinates)
                    app.BluePiece1.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Blue1CurrentPositionIndex = 4;  % Update piece index
            else
                if strcmp(app.MovementDirection(1, 1), "down")
                    % Space index of destination
                    destination = app.Blue1CurrentPositionIndex + app.DiceOutput;
                    % Contains all the indeces passed to get to destination
                    path = [app.Blue1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8    % If you hit index 8, you hit the corner
                            hitCorner = true;
                        end
                        if path(i) == 3   % If you hit index 3, you need to change directions to home
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        % Generate coordinates from current position to HomePivot
                        coordinates = linspace(app.BluePiece1.Position(2), app.BlueHomePivot(2), 100);
                        % Play animation
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        if destination > 5  % If player rolls a number that gets them to HomeBase
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(1) == -1
                                app.PiecesReachedHome(1) = 1;
                            else
                                app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                            end                            
                        else
                            destination = destination - 3; 
                            coordinates = linspace(app.BluePiece1.Position(1), app.BlueHomeCoordinates(destination, 1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue1CurrentPositionIndex = destination;
                            app.MovementDirection(1, 1) = "home";
                        end                        
                    end

                    if hitCorner && ~turnHome
                        % Generate coordinates from position to the corner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.BluePiece1.Position(2), cornerDestination, 100);
                        % Play animation
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        % Generate coordinates form corner to destination
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination,1), 100);
                        % Play animation
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = destination;
                        app.MovementDirection(1,1) = "left";    
                    else
                        if ~turnHome
                            coordinates = linspace(app.BluePiece1.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue1CurrentPositionIndex = destination;                            
                        end                       
                    end
                elseif strcmp(app.MovementDirection(1,1), "left")
                    destination = app.Blue1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.BluePiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = destination;
                        app.MovementDirection(1,1) = "up";
                    else
                        coordinates = linspace(app.BluePiece1.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(1,1), "up")
                    destination = app.Blue1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.BluePiece1.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = destination;
                        app.MovementDirection(1,1) = "right";
                    else
                        coordinates = linspace(app.BluePiece1.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(1, 1), "right")
                    destination = app.Blue1CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.BluePiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end

                        if destination <=3 
                            coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue1CurrentPositionIndex = destination;
                            if destination == 3
                                app.MovementDirection(1,1) = "home";
                            else
                                app.MovementDirection(1,1) = "down";
                            end                           
                        elseif destination > 5
                            coordinates = linspace(cornerDestination(2), app.BlueHomePivot(2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end        
                            if app.PiecesReachedHome(1) == -1
                                app.PiecesReachedHome(1) = 1;
                            else
                                app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                            end 
                        else
                            coordinates = linspace(cornerDestination(2), app.BlueHomePivot(2), 100);                            
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeCoordinates(destination - 3, 1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue1CurrentPositionIndex = destination - 3;
                            app.MovementDirection(1, 1) = "home";
                        end 

                    else
                        coordinates = linspace(app.BluePiece1.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue1CurrentPositionIndex = destination;
                    end
                else % Will run if piece direction is "home"
                    coordinates = linspace(app.BluePiece1.Position(1), app.BlueHomeBase(1), 100);
                    for i = 1 : length(coordinates)
                        app.BluePiece1.Position(1) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(1) == -1
                        app.PiecesReachedHome(1) = 1;
                    else
                        app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                    end 
                end
            end
            
            move_backwards(app, "blue", 1)      % Moves backwards if piece hit an arrow
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            % Updates current player
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: BluePiece2
        function BluePiece2ImageClicked(app, event)
            app.BluePiece1.Enable = 'off';
            app.BluePiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.BluePiece2.Position == [443,232,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.BluePiece2.Position(1) + 48;
                coordinates = linspace(app.BluePiece2.Position(1), destination, 100);
                for i = 1 : length(coordinates)
                    app.BluePiece2.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Blue2CurrentPositionIndex = 4;
            else
                if strcmp(app.MovementDirection(1, 2), "down")
                    destination = app.Blue2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                        if path(i) == 3
                            turnHome = true;
                        end
                    end
                    

                    if turnHome
                        coordinates = linspace(app.BluePiece2.Position(2), app.BlueHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        if destination > 5
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(1) == -1
                                app.PiecesReachedHome(1) = 1;
                            else
                                app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                            end                            
                        else
                            destination = destination - 3; 
                            coordinates = linspace(app.BluePiece2.Position(1), app.BlueHomeCoordinates(destination, 1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue2CurrentPositionIndex = destination;
                            app.MovementDirection(1, 2) = "home";
                        end                        
                    end

                    if hitCorner && ~turnHome
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.BluePiece2.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = destination;
                        app.MovementDirection(1,2) = "left";
                    else
                        if ~turnHome
                            coordinates = linspace(app.BluePiece2.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue2CurrentPositionIndex = destination;                            
                        end                       
                    end
                elseif strcmp(app.MovementDirection(1,2), "left")
                    destination = app.Blue2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.BluePiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = destination;
                        app.MovementDirection(1,2) = "up";
                    else
                        coordinates = linspace(app.BluePiece2.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(1,2), "up")
                    destination = app.Blue2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.BluePiece2.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = destination;
                        app.MovementDirection(1,2) = "right";
                    else
                        coordinates = linspace(app.BluePiece2.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(1, 2), "right")
                    destination = app.Blue2CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.BluePiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end

                        if destination <=3 
                            coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue2CurrentPositionIndex = destination;
                            if destination == 3
                                app.MovementDirection(1,2) = "home";
                            else
                                app.MovementDirection(1,2) = "down";
                            end                           
                        elseif destination > 5
                            coordinates = linspace(cornerDestination(2), app.BlueHomePivot(2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end        
                            if app.PiecesReachedHome(1) == -1
                                app.PiecesReachedHome(1) = 1;
                            else
                                app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                            end 
                        else
                            coordinates = linspace(cornerDestination(2), app.BlueHomePivot(2), 100);                            
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeCoordinates(destination - 3, 1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue2CurrentPositionIndex = destination - 3;
                            app.MovementDirection(1, 2) = "home";
                        end 

                    else
                        coordinates = linspace(app.BluePiece2.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue2CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.BluePiece2.Position(1), app.BlueHomeBase(1), 100);
                    for i = 1 : length(coordinates)
                        app.BluePiece2.Position(1) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(1) == -1
                        app.PiecesReachedHome(1) = 1;
                    else
                        app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                    end 
                end
            end
            
            move_backwards(app, "blue", 2)
            
            pause(0.5);
            disable_players(app);          
            is_game_over(app)    
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: BluePiece3
        function BluePiece3ImageClicked(app, event)
            app.BluePiece1.Enable = 'off';
            app.BluePiece2.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.BluePiece3.Position == [443,232,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.BluePiece3.Position(1) + 48;
                coordinates = linspace(app.BluePiece3.Position(1), destination, 100);
                for i = 1 : length(coordinates)
                    app.BluePiece3.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Blue3CurrentPositionIndex = 4;
            else
                if strcmp(app.MovementDirection(1, 3), "down")
                    destination = app.Blue3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                        if path(i) == 3
                            turnHome = true;
                        end
                    end
                    

                    if turnHome
                        coordinates = linspace(app.BluePiece3.Position(2), app.BlueHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        if destination > 5
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(1) == -1
                                app.PiecesReachedHome(1) = 1;
                            else
                                app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                            end                            
                        else
                            destination = destination - 3;
                            coordinates = linspace(app.BluePiece3.Position(1), app.BlueHomeCoordinates(destination, 1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end 
                            app.Blue3CurrentPositionIndex = destination;
                            app.MovementDirection(1, 3) = "home";
                        end                        
                    end

                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.BluePiece3.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = destination;
                        app.MovementDirection(1,3) = "left";
                    else
                        if ~turnHome
                            coordinates = linspace(app.BluePiece3.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue3CurrentPositionIndex = destination;                            
                        end                       
                    end
                elseif strcmp(app.MovementDirection(1,3), "left")
                    destination = app.Blue3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.BluePiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = destination;
                        app.MovementDirection(1,3) = "up";
                    else
                        coordinates = linspace(app.BluePiece3.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(1,3), "up")
                    destination = app.Blue3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Blue3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.BluePiece3.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = destination;
                        app.MovementDirection(1,3) = "right";
                    else
                        coordinates = linspace(app.BluePiece3.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(1, 3), "right")
                    destination = app.Blue3CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.BluePiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end

                        if destination <=3 
                            coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue3CurrentPositionIndex = destination;
                            if destination == 3
                                app.MovementDirection(1,3) = "home";
                            else
                                app.MovementDirection(1,3) = "down";
                            end                           
                        elseif destination > 5
                            coordinates = linspace(cornerDestination(2), app.BlueHomePivot(2), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end        
                            if app.PiecesReachedHome(1) == -1
                                app.PiecesReachedHome(1) = 1;
                            else
                                app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                            end 
                        else
                            coordinates = linspace(cornerDestination(2), app.BlueHomePivot(2), 100);                            
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.BlueHomePivot(1), app.BlueHomeCoordinates(destination - 3, 1), 100);
                            for i = 1 : length(coordinates)
                                app.BluePiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Blue3CurrentPositionIndex = destination - 3;
                            app.MovementDirection(1, 3) = "home";
                        end 

                    else
                        coordinates = linspace(app.BluePiece3.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.BluePiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Blue3CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.BluePiece3.Position(1), app.BlueHomeBase(1), 100);
                    for i = 1 : length(coordinates)
                        app.BluePiece3.Position(1) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(1) == -1
                        app.PiecesReachedHome(1) = 1;
                    else
                        app.PiecesReachedHome(1) = app.PiecesReachedHome(1) + 1;
                    end 
                end
            end
            
            move_backwards(app, "blue", 3)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: YellowPiece1
        function YellowPiece1ImageClicked(app, event)
            app.YellowPiece2.Enable = 'off';
            app.YellowPiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.YellowPiece1.Position == [337,76,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.YellowPiece1.Position(2) - 49;
                coordinates = linspace(app.YellowPiece1.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.YellowPiece1.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Yellow1CurrentPositionIndex = 11;
            else
                if strcmp(app.MovementDirection(2, 1), "down")
                    destination = app.Yellow1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.YellowPiece1.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        if destination < 10
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow1CurrentPositionIndex = destination;
                            app.MovementDirection(2,1) = "left";
                        elseif destination == 10
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow1CurrentPositionIndex = destination;
                            app.MovementDirection(2,1) = "left";
                        elseif destination > 12
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.YellowHomePivot(2), app.YellowHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(2) == -1
                                app.PiecesReachedHome(2) = 1;
                            else
                                app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                            end 
                        else
                            destination = destination - 10;
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.YellowPiece1.Position(2), app.YellowHomeCoordinates(destination, 2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(2, 1) = "home";
                        end                       
                    else
                        coordinates = linspace(app.YellowPiece1.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(2,1), "left")
                    destination = app.Yellow1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                        if path(i) == 10
                            turnHome = true;
                        end
                    end
                    

                    if turnHome
                        coordinates = linspace(app.YellowPiece1.Position(1), app.YellowHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        if destination > 12
                            coordinates = linspace(app.YellowHomePivot(2), app.YellowHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(2) == -1
                                app.PiecesReachedHome(2) = 1;
                            else
                                app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                            end                            
                        else
                            destination = destination - 10; 
                            if destination > 0
                                coordinates = linspace(app.YellowPiece1.Position(2), app.YellowHomeCoordinates(destination, 2), 100);
                                for i = 1 : length(coordinates)
                                    app.YellowPiece1.Position(2) = coordinates(i);
                                    pause(0.01);
                                end
                                app.Yellow1CurrentPositionIndex = destination;
                                app.MovementDirection(2, 1) = "home";
                            end
                        end                        
                    end

                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.YellowPiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = destination;
                        app.MovementDirection(2,1) = "up";
                    else
                        if ~turnHome
                            coordinates = linspace(app.YellowPiece1.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow1CurrentPositionIndex = destination;
                        end
                    end
                elseif strcmp(app.MovementDirection(2,1), "up")
                    destination = app.Yellow1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.YellowPiece1.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = destination;
                        app.MovementDirection(2,1) = "right";
                    else
                        coordinates = linspace(app.YellowPiece1.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(2, 1), "right")
                    destination = app.Yellow1CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.YellowPiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = destination;
                        app.MovementDirection(2, 1) = "down";
                    else
                        coordinates = linspace(app.YellowPiece1.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow1CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.YellowPiece1.Position(2), app.YellowHomeBase(2), 100);
                    for i = 1 : length(coordinates)
                        app.YellowPiece1.Position(2) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(2) == -1
                        app.PiecesReachedHome(2) = 1;
                    else
                        app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                    end 
                end
            end
            
            move_backwards(app, "yellow", 1)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: YellowPiece2
        function YellowPiece2ImageClicked(app, event)
            app.YellowPiece1.Enable = 'off';
            app.YellowPiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.YellowPiece2.Position == [337,76,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.YellowPiece2.Position(2) - 49;
                coordinates = linspace(app.YellowPiece2.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.YellowPiece2.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Yellow2CurrentPositionIndex = 11;
            else
                if strcmp(app.MovementDirection(2, 2), "down")
                    destination = app.Yellow2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.YellowPiece2.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        if destination < 10
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow2CurrentPositionIndex = destination;
                            app.MovementDirection(2,2) = "left";
                        elseif destination == 10
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow2CurrentPositionIndex = destination;
                            app.MovementDirection(2,2) = "left";
                        elseif destination > 12
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.YellowHomePivot(2), app.YellowHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(2) == -1
                                app.PiecesReachedHome(2) = 1;
                            else
                                app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                            end 
                        else
                            destination = destination - 10;
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.YellowPiece2.Position(2), app.YellowHomeCoordinates(destination, 2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(2, 2) = "home";
                        end                       
                    else
                        coordinates = linspace(app.YellowPiece2.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(2,2), "left")
                    destination = app.Yellow2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                        if path(i) == 10
                            turnHome = true;
                        end
                    end
                    

                    if turnHome
                        coordinates = linspace(app.YellowPiece2.Position(1), app.YellowHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        if destination > 12
                            coordinates = linspace(app.YellowHomePivot(2), app.YellowHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(2) == -1
                                app.PiecesReachedHome(2) = 1;
                            else
                                app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                            end                            
                        else
                            destination = destination - 10; 
                            if destination > 0
                                coordinates = linspace(app.YellowPiece2.Position(2), app.YellowHomeCoordinates(destination, 2), 100);
                                for i = 1 : length(coordinates)
                                    app.YellowPiece2.Position(2) = coordinates(i);
                                    pause(0.01);
                                end
                                app.Yellow2CurrentPositionIndex = destination;
                                app.MovementDirection(2, 2) = "home";
                            end
                        end                        
                    end

                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.YellowPiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = destination;
                        app.MovementDirection(2,2) = "up";
                    else
                        if ~turnHome
                            coordinates = linspace(app.YellowPiece2.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow2CurrentPositionIndex = destination;
                        end                        
                    end
                elseif strcmp(app.MovementDirection(2,2), "up")
                    destination = app.Yellow2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.YellowPiece2.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = destination;
                        app.MovementDirection(2,2) = "right";
                    else
                        coordinates = linspace(app.YellowPiece2.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(2, 2), "right")
                    destination = app.Yellow2CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.YellowPiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = destination;
                        app.MovementDirection(2, 2) = "down";
                    else
                        coordinates = linspace(app.YellowPiece2.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow2CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.YellowPiece2.Position(2), app.YellowHomeBase(2), 100);
                    for i = 1 : length(coordinates)
                        app.YellowPiece2.Position(2) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(2) == -1
                        app.PiecesReachedHome(2) = 1;
                    else
                        app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                    end 
                end
            end
            
            move_backwards(app, "yellow", 2)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: YellowPiece3
        function YellowPiece3ImageClicked(app, event)
            app.YellowPiece1.Enable = 'off';
            app.YellowPiece2.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.YellowPiece3.Position == [337,76,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.YellowPiece3.Position(2) - 49;
                coordinates = linspace(app.YellowPiece3.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.YellowPiece3.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Yellow3CurrentPositionIndex = 11;
            else
                if strcmp(app.MovementDirection(2, 3), "down")
                    destination = app.Yellow3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.YellowPiece3.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        if destination < 10
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow3CurrentPositionIndex = destination;
                            app.MovementDirection(2,3) = "left";
                        elseif destination == 10
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow3CurrentPositionIndex = destination;
                            app.MovementDirection(2,3) = "left";
                        elseif destination > 12
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.YellowHomePivot(2), app.YellowHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(2) == -1
                                app.PiecesReachedHome(2) = 1;
                            else
                                app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                            end 
                        else
                            destination = destination - 10;
                            coordinates = linspace(app.BottomRightCornerSpace(1), app.YellowHomePivot(1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            coordinates = linspace(app.YellowPiece3.Position(2), app.YellowHomeCoordinates(destination, 2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(2, 3) = "home";
                        end                       
                    else
                        coordinates = linspace(app.YellowPiece3.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(2,3), "left")
                    destination = app.Yellow3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                        if path(i) == 10
                            turnHome = true;
                        end
                    end
                    

                    if turnHome
                        coordinates = linspace(app.YellowPiece3.Position(1), app.YellowHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        if destination > 12
                            coordinates = linspace(app.YellowHomePivot(2), app.YellowHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(2) == -1
                                app.PiecesReachedHome(2) = 1;
                            else
                                app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                            end                            
                        else
                            destination = destination - 10; 
                            if destination > 0
                                coordinates = linspace(app.YellowPiece3.Position(2), app.YellowHomeCoordinates(destination, 2), 100);
                                for i = 1 : length(coordinates)
                                    app.YellowPiece3.Position(2) = coordinates(i);
                                    pause(0.01);
                                end 
                                app.Yellow3CurrentPositionIndex = destination;
                                app.MovementDirection(2, 3) = "home";
                            end
                        end                        
                    end

                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.YellowPiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = destination;
                        app.MovementDirection(2,3) = "up";
                    else
                        if ~turnHome
                            coordinates = linspace(app.YellowPiece3.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.YellowPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Yellow3CurrentPositionIndex = destination;
                        end                        
                    end
                elseif strcmp(app.MovementDirection(2,3), "up")
                    destination = app.Yellow3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Yellow3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.YellowPiece3.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = destination;
                        app.MovementDirection(2,3) = "right";
                    else
                        coordinates = linspace(app.YellowPiece3.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = destination;
                    end
                elseif strcmp(app.MovementDirection(2, 3), "right")
                    destination = app.Yellow3CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.YellowPiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = destination;
                        app.MovementDirection(2, 3) = "down";
                    else
                        coordinates = linspace(app.YellowPiece3.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.YellowPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Yellow3CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.YellowPiece3.Position(2), app.YellowHomeBase(2), 100);
                    for i = 1 : length(coordinates)
                        app.YellowPiece3.Position(2) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(2) == -1
                        app.PiecesReachedHome(2) = 1;
                    else
                        app.PiecesReachedHome(2) = app.PiecesReachedHome(2) + 1;
                    end 
                end
            end
            
            move_backwards(app, "yellow", 3)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
            app.RollDiceButton.Enable = 'off';
            
            dice1 = randi([1 4]);   % Generate random number for first die
            dice2 = randi([1 4]);   % Generate random number for second die
            app.DiceOutput = dice1 + dice2;            
            
            % Dice rolling animation
            for i = 1 : 10
                number = randi([1 4]);
                % Picks between 4 different dice display outputs to
                % randomize animation (which dice are being displayed)
                switch number
                    case 1
                        app.Dice1.Visible = 'on';                       
                        app.Dice2.Visible = 'off';
                        app.Dice3.Visible = 'off';
                        app.Dice4.Visible = 'off';
                        pause(0.1);                        
                        app.Dice1_2.Visible = 'off';
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'on';
                        app.Dice4_2.Visible = 'off';
                        pause(0.1);
                    case 2
                        app.Dice1.Visible = 'off';                       
                        app.Dice2.Visible = 'on';
                        app.Dice3.Visible = 'off';
                        app.Dice4.Visible = 'off';
                        pause(0.1);                        
                        app.Dice1_2.Visible = 'on';
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'off';
                        pause(0.1);
                    case 3
                        app.Dice1.Visible = 'off';                       
                        app.Dice2.Visible = 'off';
                        app.Dice3.Visible = 'off';
                        app.Dice4.Visible = 'on';
                        pause(0.1);                       
                        app.Dice1_2.Visible = 'off';
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'on';
                        pause(0.1);
                    case 4
                        app.Dice1.Visible = 'off';                       
                        app.Dice2.Visible = 'off';
                        app.Dice3.Visible = 'on';
                        app.Dice4.Visible = 'off';
                        pause(0.1);                       
                        app.Dice1_2.Visible = 'on';
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'off';
                        pause(0.1);
                end                
            end  
            
            % Selects dice to match the output of the random number between
            % 1 and 8
            switch app.DiceOutput
                case 2
                    app.Dice1.Visible = 'on';
                    app.Dice1_2.Visible = 'on';                                         
                    app.Dice2.Visible = 'off';
                    app.Dice3.Visible = 'off';
                    app.Dice4.Visible = 'off';                                           
                    app.Dice2_2.Visible = 'off';
                    app.Dice3_2.Visible = 'off';
                    app.Dice4_2.Visible = 'off';
                case 3
                    app.Dice1.Visible = 'on';
                    app.Dice2_2.Visible = 'on';                  
                    app.Dice1_2.Visible = 'off';
                    app.Dice2.Visible = 'off';
                    app.Dice3.Visible = 'off';
                    app.Dice4.Visible = 'off';                                                               
                    app.Dice3_2.Visible = 'off';
                    app.Dice4_2.Visible = 'off';
                case 4
                    options = randi([1 2]); % 2 options for which dice to display
                    if options == 1
                        app.Dice2_2.Visible = 'on';
                        app.Dice2.Visible = 'on';
                        app.Dice1.Visible = 'off';                                       
                        app.Dice1_2.Visible = 'off';                    
                        app.Dice3.Visible = 'off';
                        app.Dice4.Visible = 'off';                                                               
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'off';
                    else
                        app.Dice2_2.Visible = 'off';
                        app.Dice2.Visible = 'off';
                        app.Dice1.Visible = 'off';                                       
                        app.Dice1_2.Visible = 'on';                    
                        app.Dice3.Visible = 'on';
                        app.Dice4.Visible = 'off';                                                               
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'off';
                    end
                case 5
                    options = randi([1 2]);   % 2 options for which dice to display
                    if options == 1
                        app.Dice2_2.Visible = 'on';
                        app.Dice2.Visible = 'off';
                        app.Dice1.Visible = 'off';                                       
                        app.Dice1_2.Visible = 'off';                    
                        app.Dice3.Visible = 'on';
                        app.Dice4.Visible = 'off';                                                               
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'off';
                    else
                        app.Dice2_2.Visible = 'off';
                        app.Dice2.Visible = 'off';
                        app.Dice1.Visible = 'on';                                       
                        app.Dice1_2.Visible = 'off';                    
                        app.Dice3.Visible = 'off';
                        app.Dice4.Visible = 'off';                                                               
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'on';
                    end
                case 6
                    options = randi([1 2]); % 2 options for which dice to display
                    if options == 1
                        app.Dice1.Visible = 'off';
                        app.Dice2.Visible = 'off';                                                                                                        
                        app.Dice3.Visible = 'on';
                        app.Dice4.Visible = 'off';
                        app.Dice1_2.Visible = 'off';
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'on';
                        app.Dice4_2.Visible = 'off';
                    else
                        app.Dice1.Visible = 'off';
                        app.Dice2.Visible = 'on';
                        app.Dice3.Visible = 'off';  
                        app.Dice4.Visible = 'off';
                        app.Dice1_2.Visible = 'off';                                                                      
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'on';
                    end
                case 7
                    options = randi([1 2]); % 2 options for which dice to display
                    if options == 1
                        app.Dice1.Visible = 'off';
                        app.Dice2.Visible = 'off';                                                                                                        
                        app.Dice3.Visible = 'off';
                        app.Dice4.Visible = 'on';
                        app.Dice1_2.Visible = 'off';
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'on';
                        app.Dice4_2.Visible = 'off';
                    else
                        app.Dice1.Visible = 'off';
                        app.Dice2.Visible = 'off';
                        app.Dice3.Visible = 'on';  
                        app.Dice4.Visible = 'off';
                        app.Dice1_2.Visible = 'off';                                                                      
                        app.Dice2_2.Visible = 'off';
                        app.Dice3_2.Visible = 'off';
                        app.Dice4_2.Visible = 'on';
                    end
                otherwise
                    app.Dice1.Visible = 'off';
                    app.Dice2.Visible = 'off';                                                                                                        
                    app.Dice3.Visible = 'off';
                    app.Dice4.Visible = 'on';
                    app.Dice1_2.Visible = 'off';
                    app.Dice2_2.Visible = 'off';
                    app.Dice3_2.Visible = 'off';
                    app.Dice4_2.Visible = 'on';                   
            end
            
            disable_players(app);
            
            % Checks if game has entered Critical Stage (if a player has 2
            % pieces in their home base)
            critical = false;
            for i = 1 : 4
                if app.PiecesReachedHome(i) == 2
                    critical = true;
                end
            end
            
            % Display the Critical Stage switch if conditions are met
            if app.DiceOutput == 8 && critical
                pause(0.3);
                app.Switch.Visible = 'on';
                app.Switch.Enable = 'on';
                pause(0.5);
                app.Switch.Visible = 'off';
                app.Switch.Enable = 'off';
            end
            
            % Updates player pieces
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                if ~is_home(app, "blue", 1)
                    app.BluePiece1.Enable = 'on';
                end
                if ~is_home(app, "blue", 2)
                    app.BluePiece2.Enable = 'on';
                end
                if ~is_home(app, "blue", 3)
                    app.BluePiece3.Enable = 'on';
                end
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               if ~is_home(app, "yellow", 1)                   
                   app.YellowPiece1.Enable = 'on';
               end
               if ~is_home(app, "yellow", 2)
                   app.YellowPiece2.Enable = 'on';
               end
               if ~is_home(app, "yellow", 3)
                   app.YellowPiece3.Enable = 'on';
               end
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               if ~is_home(app, "green", 1)
                   app.GreenPiece1.Enable = 'on';
               end
               if ~is_home(app, "green", 2)
                   app.GreenPiece2.Enable = 'on';
               end
               if ~is_home(app, "green", 3)
                   app.GreenPiece3.Enable = 'on';
               end
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")
               if ~is_home(app, "red", 1)
                   app.RedPiece1.Enable = 'on';
               end
               if ~is_home(app, "red", 2)
                   app.RedPiece2.Enable = 'on';
               end
               if ~is_home(app, "red", 3)
                   app.RedPiece3.Enable = 'on';
               end
            end
            
            % Checks if PlayerTurnCounter goes beyond ActivePlayer array
            % index
            if mod(app.PlayerTurnCounter, length(app.ActivePlayers)) == 0
                app.PlayerTurnCounter = 0;
            end
            
            % Updates current player turn
            app.PlayerTurnCounter = app.PlayerTurnCounter + 1;                        
        end

        % Image clicked function: GreenPiece1
        function GreenPiece1ImageClicked(app, event)
            app.GreenPiece2.Enable = 'off';
            app.GreenPiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.GreenPiece1.Position == [169,180,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = [119,180,34,33];
                coordinates = linspace(app.GreenPiece1.Position(1), destination(1), 100);
                for i = 1 : length(coordinates)
                    app.GreenPiece1.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Green1CurrentPositionIndex = 18;
                app.MovementDirection(3, 1) = "up";
            else
                if strcmp(app.MovementDirection(3, 1), "down")
                    destination = app.Green1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.GreenPiece1.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = destination;
                        app.MovementDirection(3, 1) = "left";
                    else
                        coordinates = linspace(app.GreenPiece1.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(3,1), "left")
                    destination = app.Green1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                        if path(i) == 17
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        coordinates = linspace(app.GreenPiece1.Position(2), app.GreenHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 19
                            coordinates = linspace(app.GreenHomePivot(1), app.GreenHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(3) == -1
                                app.PiecesReachedHome(3) = 1;
                            else
                                app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                            end                            
                        elseif destination == 17
                            coordinates = linspace(app.GreenPiece1.Position(2), app.GreenHomePivot(2), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green1CurrentPositionIndex = 17;
                            app.MovementDirection(3, 1) = "left";
                        else                          
                            destination = destination - 17;
                            coordinates = linspace(app.GreenPiece1.Position(1), app.GreenHomeCoordinates(destination, 1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(3, 1) = "home";
                        end                        
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = destination;
                        app.MovementDirection(3,1) = "up";
                    else
                        if ~turnHome
                            coordinates = linspace(app.GreenPiece1.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green1CurrentPositionIndex = destination;
                        end                        
                    end
                elseif strcmp(app.MovementDirection(3,1), "up")
                    destination = app.Green1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                        if path(i) == 17
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.BottomLeftCornerSpace(2), app.GreenHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 19
                            coordinates = linspace(app.GreenPiece1.Position(1), app.GreenHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(3) == -1
                                app.PiecesReachedHome(3) = 1;
                            else
                                app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                            end
                        elseif destination == 17
                            app.Green1CurrentPositionIndex = 17;
                            app.MovementDirection(3, 1) = "left";
                        else
                            destination = destination - 17;
                            coordinates = linspace(app.GreenPiece1.Position(1), app.GreenHomeCoordinates(destination,  1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(3, 1) = "home";
                        end
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece1.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = destination;
                        app.MovementDirection(3,1) = "right";
                    else
                        if ~turnHome
                            coordinates = linspace(app.GreenPiece1.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green1CurrentPositionIndex = destination;
                        end                       
                    end
                elseif strcmp(app.MovementDirection(3, 1), "right")
                    destination = app.Green1CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.GreenPiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = destination;
                        app.MovementDirection(3, 1) = "down";
                    else
                        coordinates = linspace(app.GreenPiece1.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green1CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.GreenPiece1.Position(1), app.GreenHomeBase(1), 100);
                    for i = 1 : length(coordinates)
                        app.GreenPiece1.Position(1) = coordinates(i); 
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(3) == -1
                        app.PiecesReachedHome(3) = 1;
                    else
                        app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                    end 
                end
            end
            
            move_backwards(app, "green", 1)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: GreenPiece2
        function GreenPiece2ImageClicked(app, event)
            app.GreenPiece1.Enable = 'off';
            app.GreenPiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.GreenPiece2.Position == [169,180,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = [119,180,34,33];
                coordinates = linspace(app.GreenPiece2.Position(1), destination(1), 100);
                for i = 1 : length(coordinates)
                    app.GreenPiece2.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Green2CurrentPositionIndex = 18;
                app.MovementDirection(3, 2) = "up";
            else
                if strcmp(app.MovementDirection(3, 2), "down")
                    destination = app.Green2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.GreenPiece2.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = destination;
                        app.MovementDirection(3, 2) = "left";
                    else
                        coordinates = linspace(app.GreenPiece2.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(3,2), "left")
                    destination = app.Green2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                        if path(i) == 17
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        coordinates = linspace(app.GreenPiece2.Position(2), app.GreenHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 19
                            coordinates = linspace(app.GreenHomePivot(1), app.GreenHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(3) == -1
                                app.PiecesReachedHome(3) = 1;
                            else
                                app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                            end                            
                        elseif destination == 17
                            coordinates = linspace(app.GreenPiece2.Position(2), app.GreenHomePivot(2), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green2CurrentPositionIndex = 17;
                            app.MovementDirection(3, 2) = "left";
                        else                             
                            destination = destination - 17;
                            coordinates = linspace(app.GreenPiece2.Position(1), app.GreenHomeCoordinates(destination, 1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(3, 2) = "home";
                        end                        
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = destination;
                        app.MovementDirection(3,2) = "up";
                    else
                        if ~turnHome
                            coordinates = linspace(app.GreenPiece2.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green2CurrentPositionIndex = destination;
                        end                        
                    end
                elseif strcmp(app.MovementDirection(3,2), "up")
                    destination = app.Green2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                        if path(i) == 17
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.BottomLeftCornerSpace(2), app.GreenHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 19
                            coordinates = linspace(app.GreenPiece2.Position(1), app.GreenHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(3) == -1
                                app.PiecesReachedHome(3) = 1;
                            else
                                app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                            end
                        elseif destination == 17
                            app.Green2CurrentPositionIndex = 17;
                            app.MovementDirection(3, 2) = "left";
                        else
                            destination = destination - 17;
                            coordinates = linspace(app.GreenPiece2.Position(1), app.GreenHomeCoordinates(destination,  1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(3, 2) = "home";
                        end
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece2.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = destination;
                        app.MovementDirection(3,2) = "right";
                    else
                        if ~turnHome
                           coordinates = linspace(app.GreenPiece2.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green2CurrentPositionIndex = destination; 
                        end                      
                    end
                elseif strcmp(app.MovementDirection(3, 2), "right")
                    destination = app.Green2CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.GreenPiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = destination;
                        app.MovementDirection(3, 2) = "down";
                    else
                        coordinates = linspace(app.GreenPiece2.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green2CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.GreenPiece2.Position(1), app.GreenHomeBase(1), 100);
                    for i = 1 : length(coordinates)
                        app.GreenPiece2.Position(1) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(3) == -1
                        app.PiecesReachedHome(3) = 1;
                    else
                        app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                    end 
                end
            end
            
            move_backwards(app, "green", 2)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: GreenPiece3
        function GreenPiece3ImageClicked(app, event)
            app.GreenPiece1.Enable = 'off';
            app.GreenPiece2.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.GreenPiece3.Position == [169,180,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = [119,180,34,33];
                coordinates = linspace(app.GreenPiece3.Position(1), destination(1), 100);
                for i = 1 : length(coordinates)
                    app.GreenPiece3.Position(1) = coordinates(i);
                    pause(0.01);
                end
                app.Green3CurrentPositionIndex = 18;
                app.MovementDirection(3, 3) = "up";
            else
                if strcmp(app.MovementDirection(3, 3), "down")
                    destination = app.Green3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.GreenPiece3.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination, 1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = destination;
                        app.MovementDirection(3, 3) = "left";
                    else
                        coordinates = linspace(app.GreenPiece3.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(3,3), "left")
                    destination = app.Green3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                        if path(i) == 17
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        coordinates = linspace(app.GreenPiece3.Position(2), app.GreenHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 19
                            coordinates = linspace(app.GreenHomePivot(1), app.GreenHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(3) == -1
                                app.PiecesReachedHome(3) = 1;
                            else
                                app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                            end                            
                        elseif destination == 17
                            coordinates = linspace(app.GreenPiece3.Position(2), app.GreenHomePivot(2), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green3CurrentPositionIndex = 17;
                            app.MovementDirection(3, 3) = "left";
                        else 
                            destination = destination - 17;
                            coordinates = linspace(app.GreenPiece3.Position(1), app.GreenHomeCoordinates(destination, 1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(3, 3) = "home";
                        end                        
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = destination;
                        app.MovementDirection(3,3) = "up";
                    else
                        if ~turnHome
                            coordinates = linspace(app.GreenPiece3.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green3CurrentPositionIndex = destination;
                        end                        
                    end
                elseif strcmp(app.MovementDirection(3,3), "up")
                    destination = app.Green3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Green3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                        if path(i) == 17
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.BottomLeftCornerSpace(2), app.GreenHomePivot(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 19
                            coordinates = linspace(app.GreenPiece3.Position(1), app.GreenHomeBase(1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(3) == -1
                                app.PiecesReachedHome(3) = 1;
                            else
                                app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                            end
                        elseif destination == 17
                            app.Green3CurrentPositionIndex = 17;
                            app.MovementDirection(3, 3) = "left";
                        else
                            destination = destination - 17;
                            coordinates = linspace(app.GreenPiece3.Position(1), app.GreenHomeCoordinates(destination,  1), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(3, 3) = "home";
                        end
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.GreenPiece3.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = destination;
                        app.MovementDirection(3,3) = "right";
                    else
                        if ~turnHome
                            coordinates = linspace(app.GreenPiece3.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.GreenPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Green3CurrentPositionIndex = destination;
                        end                      
                    end
                elseif strcmp(app.MovementDirection(3, 3), "right")
                    destination = app.Green3CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.GreenPiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = destination;
                        app.MovementDirection(3, 3) = "down";
                    else
                        coordinates = linspace(app.GreenPiece3.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.GreenPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Green3CurrentPositionIndex = destination;
                    end
                else 
                    coordinates = linspace(app.GreenPiece3.Position(1), app.GreenHomeBase(1), 100);
                    for i = 1 : length(coordinates)
                        app.GreenPiece3.Position(1) = coordinates(i);
                        pause(0.01);
                    end
                    if app.PiecesReachedHome(3) == -1
                        app.PiecesReachedHome(3) = 1;
                    else
                        app.PiecesReachedHome(3) = app.PiecesReachedHome(3) + 1;
                    end 
                end
            end
            
            move_backwards(app, "green", 3)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: RedPiece1
        function RedPiece1ImageClicked(app, event)
            app.RedPiece2.Enable = 'off';
            app.RedPiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.RedPiece1.Position == [285,330,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.SquareLocations(25, 2);
                coordinates = linspace(app.RedPiece1.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.RedPiece1.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Red1CurrentPositionIndex = 25;
                app.MovementDirection(4, 1) = "right";
            else
                if strcmp(app.MovementDirection(4, 1), "down")
                    destination = app.Red1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.RedPiece1.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = destination;
                        app.MovementDirection(4, 1) = "left";
                    else
                        coordinates = linspace(app.RedPiece1.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(4, 1), "left")
                    destination = app.Red1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.RedPiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = destination;
                        app.MovementDirection(4, 1) = "up";
                    else
                        coordinates = linspace(app.RedPiece1.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = destination;                        
                    end
                elseif strcmp(app.MovementDirection(4, 1), "up")
                    destination = app.Red1CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                        if path(i) == 24
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.RedPiece1.Position(2), app.TopLeftCornerSpace(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        coordinates = linspace(app.TopLeftCornerSpace(1), app.RedHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 26
                            coordinates = linspace(app.RedPiece1.Position(2), app.RedHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(4) == -1
                                app.PiecesReachedHome(4) = 1;
                            else
                                app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                            end
                        elseif destination == 24
                            app.Red1CurrentPositionIndex = 24;
                            app.MovementDirection(4, 1) = "home";
                        else
                            destination = destination - 24;
                            coordinates = linspace(app.RedPiece1.Position(2), app.RedHomeCoordinates(destination,  2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(4, 1) = "home";
                        end
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.RedPiece1.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = destination;
                        app.MovementDirection(4, 1) = "right";
                    else
                        if ~turnHome
                            coordinates = linspace(app.RedPiece1.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Red1CurrentPositionIndex = destination;
                        end                      
                    end
                elseif strcmp(app.MovementDirection(4, 1), "right")
                    destination = app.Red1CurrentPositionIndex + app.DiceOutput;                   
                    path = [app.Red1CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 24
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.RedPiece1.Position(1), app.RedHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination == 24
                            app.Red1CurrentPositionIndex = 24;
                            app.MovementDirection(4, 1) = "home";
                        elseif destination > 26
                            coordinates = linspace(app.RedPiece1.Position(2), app.RedHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(4) == -1
                                app.PiecesReachedHome(4) = 1;
                            else
                                app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                            end
                        else
                            destination = destination - 24;
                            coordinates = linspace(app.RedPiece1.Position(2), app.RedHomeCoordinates(destination, 2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece1.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(4, 1) = "home";
                        end
                    end
                    
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.RedPiece1.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red1CurrentPositionIndex = destination;
                        app.MovementDirection(4, 1) = "down";
                    else
                        if ~turnHome
                            coordinates = linspace(app.RedPiece1.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece1.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Red1CurrentPositionIndex = destination;
                        end                       
                    end
                else % updated
                    nearHome = app.RedPiece1.Position == app.SquareLocations(24);
                    destination = app.Red1CurrentPositionIndex + app.DiceOutput;
                    if all(nearHome) && destination == 26
                        coordinates = linspace(app.RedPiece1.Position(2), app.RedHomeCoordinates(2, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                    else
                        coordinates = linspace(app.RedPiece1.Position(2), app.RedHomeBase(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece1.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        if app.PiecesReachedHome(4) == -1
                            app.PiecesReachedHome(4) = 1;
                        else
                            app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                        end
                    end                     
                end
            end
            
            move_backwards(app, "red", 1)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: RedPiece2
        function RedPiece2ImageClicked(app, event)
            app.RedPiece1.Enable = 'off';
            app.RedPiece3.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.RedPiece2.Position == [285,330,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.SquareLocations(25, 2);
                coordinates = linspace(app.RedPiece2.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.RedPiece2.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Red2CurrentPositionIndex = 25;
                app.MovementDirection(4, 2) = "right";
            else
                if strcmp(app.MovementDirection(4, 2), "down")
                    destination = app.Red2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.RedPiece2.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = destination;
                        app.MovementDirection(4, 2) = "left";
                    else
                        coordinates = linspace(app.RedPiece2.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(4, 2), "left")
                    destination = app.Red2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.RedPiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = destination;
                        app.MovementDirection(4, 2) = "up";
                    else
                        coordinates = linspace(app.RedPiece2.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = destination;                        
                    end
                elseif strcmp(app.MovementDirection(4, 2), "up")
                    destination = app.Red2CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                        if path(i) == 24
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.RedPiece2.Position(2), app.TopLeftCornerSpace(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        coordinates = linspace(app.TopLeftCornerSpace(1), app.RedHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 26
                            coordinates = linspace(app.RedPiece2.Position(2), app.RedHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(4) == -1
                                app.PiecesReachedHome(4) = 1;
                            else
                                app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                            end
                        elseif destination == 24
                            app.Red2CurrentPositionIndex = 24;
                            app.MovementDirection(4, 2) = "home";
                        else
                            destination = destination - 24;
                            coordinates = linspace(app.RedPiece2.Position(2), app.RedHomeCoordinates(destination,  2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(4, 2) = "home";
                        end
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.RedPiece2.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = destination;
                        app.MovementDirection(4, 2) = "right";
                    else
                        if ~turnHome
                            coordinates = linspace(app.RedPiece2.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Red2CurrentPositionIndex = destination;
                        end                      
                    end
                elseif strcmp(app.MovementDirection(4, 2), "right")
                    destination = app.Red2CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    path = [app.Red2CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 24
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.RedPiece2.Position(1), app.RedHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination == 24
                            app.Red2CurrentPositionIndex = 24;
                            app.MovementDirection(4, 2) = "home";
                        elseif destination > 26
                            coordinates = linspace(app.RedPiece2.Position(2), app.RedHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(4) == -1
                                app.PiecesReachedHome(4) = 1;
                            else
                                app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                            end
                        else
                            destination = destination - 24;
                            coordinates = linspace(app.RedPiece2.Position(2), app.RedHomeCoordinates(destination, 2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece2.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(4, 2) = "home";
                        end
                    end
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.RedPiece2.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red2CurrentPositionIndex = destination;
                        app.MovementDirection(4, 2) = "down";
                    else
                        if ~turnHome
                            coordinates = linspace(app.RedPiece2.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece2.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Red2CurrentPositionIndex = destination;
                        end                       
                    end
                else % updated
                    nearHome = app.RedPiece2.Position == app.SquareLocations(24);
                    destination = app.Red2CurrentPositionIndex + app.DiceOutput;
                    if all(nearHome) && destination == 26
                        coordinates = linspace(app.RedPiece2.Position(2), app.RedHomeCoordinates(2, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                    else
                        coordinates = linspace(app.RedPiece2.Position(2), app.RedHomeBase(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece2.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        if app.PiecesReachedHome(4) == -1
                            app.PiecesReachedHome(4) = 1;
                        else
                            app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                        end
                    end                     
                end
            end
            
            move_backwards(app, "red", 2)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Image clicked function: RedPiece3
        function RedPiece3ImageClicked(app, event)
            app.RedPiece1.Enable = 'off';
            app.RedPiece2.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            
            atStart = app.RedPiece3.Position == [285,330,34,33];
            hitCorner = false;
            turnHome = false;
            
            if all(atStart)
                destination = app.SquareLocations(25, 2);
                coordinates = linspace(app.RedPiece3.Position(2), destination, 100);
                for i = 1 : length(coordinates)
                    app.RedPiece3.Position(2) = coordinates(i);
                    pause(0.01);
                end
                app.Red3CurrentPositionIndex = 25;
                app.MovementDirection(4, 3) = "right";
            else
                if strcmp(app.MovementDirection(4, 3), "down")
                    destination = app.Red3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 8
                            hitCorner = true;
                        end
                    end
                                        
                    if hitCorner
                        cornerDestination = app.BottomRightCornerSpace(2);
                        coordinates = linspace(app.RedPiece3.Position(2), cornerDestination, 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        
                        coordinates = linspace(app.BottomRightCornerSpace(1), app.SquareLocations(destination, 1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = destination;
                        app.MovementDirection(4, 3) = "left";
                    else
                        coordinates = linspace(app.RedPiece3.Position(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = destination;                                                  
                    end
                elseif strcmp(app.MovementDirection(4, 3), "left")
                    destination = app.Red3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 15
                            hitCorner = true;
                        end
                    end
                    
                    if hitCorner
                        cornerDestination = app.BottomLeftCornerSpace;
                        coordinates = linspace(app.RedPiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination,2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = destination;
                        app.MovementDirection(4, 3) = "up";
                    else
                        coordinates = linspace(app.RedPiece3.Position(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = destination;                        
                    end
                elseif strcmp(app.MovementDirection(4, 3), "up")
                    destination = app.Red3CurrentPositionIndex + app.DiceOutput;
                    path = [app.Red3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 22
                            hitCorner = true;
                        end
                        if path(i) == 24
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.RedPiece3.Position(2), app.TopLeftCornerSpace(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        
                        coordinates = linspace(app.TopLeftCornerSpace(1), app.RedHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination > 26
                            coordinates = linspace(app.RedPiece3.Position(2), app.RedHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(4) == -1
                                app.PiecesReachedHome(4) = 1;
                            else
                                app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                            end
                        elseif destination == 24
                            app.Red3CurrentPositionIndex = 24;
                            app.MovementDirection(4, 3) = "home";
                        else
                            destination = destination - 24;
                            coordinates = linspace(app.RedPiece3.Position(2), app.RedHomeCoordinates(destination,  2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(4, 3) = "home";
                        end
                    end
                    
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopLeftCornerSpace;
                        coordinates = linspace(app.RedPiece3.Position(2), cornerDestination(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(1), app.SquareLocations(destination,1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = destination;
                        app.MovementDirection(4, 3) = "right";
                    else
                        if ~turnHome
                            coordinates = linspace(app.RedPiece3.Position(2), app.SquareLocations(destination,2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.Red3CurrentPositionIndex = destination;
                        end                      
                    end
                elseif strcmp(app.MovementDirection(4, 3), "right")
                    destination = app.Red3CurrentPositionIndex + app.DiceOutput;
                    if destination > 28
                        destination = destination - 28;
                        hitCorner = true;
                    end
                    path = [app.Red3CurrentPositionIndex:destination];                   
                    
                    for i = 1 : length(path)
                        if path(i) == 24
                            turnHome = true;
                        end
                    end
                    
                    if turnHome
                        coordinates = linspace(app.RedPiece3.Position(1), app.RedHomePivot(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);
                        end
                        
                        if destination == 24
                            app.Red3CurrentPositionIndex = 24;
                            app.MovementDirection(4, 3) = "home";
                        elseif destination > 26
                            coordinates = linspace(app.RedPiece3.Position(2), app.RedHomeBase(2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            if app.PiecesReachedHome(4) == -1
                                app.PiecesReachedHome(4) = 1;
                            else
                                app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                            end
                        else
                            destination = destination - 24;
                            coordinates = linspace(app.RedPiece3.Position(2), app.RedHomeCoordinates(destination, 2), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece3.Position(2) = coordinates(i);
                                pause(0.01);
                            end
                            app.MovementDirection(4, 3) = "home";
                        end
                    end
                    if hitCorner && ~turnHome
                        cornerDestination = app.TopRightCornerSpace;
                        coordinates = linspace(app.RedPiece3.Position(1), cornerDestination(1), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(1) = coordinates(i);
                            pause(0.01);                           
                        end
                        coordinates = linspace(cornerDestination(2), app.SquareLocations(destination, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        app.Red3CurrentPositionIndex = destination;
                        app.MovementDirection(4, 3) = "down";
                    else
                        if ~turnHome
                            coordinates = linspace(app.RedPiece3.Position(1), app.SquareLocations(destination,1), 100);
                            for i = 1 : length(coordinates)
                                app.RedPiece3.Position(1) = coordinates(i);
                                pause(0.01);
                            end
                            app.Red3CurrentPositionIndex = destination;
                        end                       
                    end
                else % updated
                    nearHome = app.RedPiece3.Position == app.SquareLocations(24);
                    destination = app.Red3CurrentPositionIndex + app.DiceOutput;
                    if all(nearHome) && destination == 26
                        coordinates = linspace(app.RedPiece3.Position(2), app.RedHomeCoordinates(2, 2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                    else
                        coordinates = linspace(app.RedPiece3.Position(2), app.RedHomeBase(2), 100);
                        for i = 1 : length(coordinates)
                            app.RedPiece3.Position(2) = coordinates(i);
                            pause(0.01);
                        end
                        if app.PiecesReachedHome(4) == -1
                            app.PiecesReachedHome(4) = 1;
                        else
                            app.PiecesReachedHome(4) = app.PiecesReachedHome(4) + 1;
                        end
                    end                     
                end
            end
            
            move_backwards(app, "red", 3)
            
            pause(0.5);
            disable_players(app);
            is_game_over(app);
            app.RollDiceButton.Enable = 'on';
            
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "blue")
                app.PlayerTurnLabel.BackgroundColor = [0.07,0.62,1.00];
                app.PlayerNameLabel.Text = app.StartApp.BluePlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "yellow")
               app.PlayerTurnLabel.BackgroundColor = [1.00,1.00,0.07];
               app.PlayerNameLabel.Text = app.StartApp.YellowPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "green")
               app.PlayerTurnLabel.BackgroundColor = [0.00,1.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.GreenPlayerName;
            end
            if strcmp(string(app.ActivePlayers(app.PlayerTurnCounter)), "red")              
               app.PlayerTurnLabel.BackgroundColor = [1.00,0.00,0.00];
               app.PlayerNameLabel.Text = app.StartApp.RedPlayerName;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;            
            
            if strcmp(value, 'On')
                disable_players(app);
                app.RollDiceButton.Enable = 'off';
                app.Switch.Visible = 'on';
                app.Switch.Enable = 'off';
                WinningScreen(app); % Open WinningScreen
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GameBoard = uiimage(app.UIFigure);
            app.GameBoard.ImageSource = 'SorryGameBoard.jpg';
            app.Dice2 = uiimage(app.UIFigure);
            app.Dice2.ImageSource = 'dice.2_1.jpg';
            app.Dice4 = uiimage(app.UIFigure);
            app.Dice4.ImageSource = 'dice.4.jpg';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.PlayerTurnLabel = uilabel(app.UIFigure);
            app.PlayerTurnLabel.Text = 'Player Turn:';
            app.PlayerNameLabel = uilabel(app.UIFigure);
            app.PlayerNameLabel.Text = 'Player Name';
            app.BluePiece3 = uiimage(app.UIFigure);
            app.BluePiece3.ImageClickedFcn = createCallbackFcn(app, @BluePiece3ImageClicked, true);
            app.BluePiece3.ImageSource = 'player_blue.png';
            app.BluePiece2 = uiimage(app.UIFigure);
            app.BluePiece2.ImageClickedFcn = createCallbackFcn(app, @BluePiece2ImageClicked, true);
            app.BluePiece2.ImageSource = 'player_blue.png';
            app.YellowPiece1 = uiimage(app.UIFigure);
            app.YellowPiece1.ImageClickedFcn = createCallbackFcn(app, @YellowPiece1ImageClicked, true);
            app.YellowPiece1.ImageSource = 'player_yellow.png';
            app.YellowPiece2 = uiimage(app.UIFigure);
            app.YellowPiece2.ImageClickedFcn = createCallbackFcn(app, @YellowPiece2ImageClicked, true);
            app.YellowPiece2.ImageSource = 'player_yellow.png';
            app.GreenPiece2 = uiimage(app.UIFigure);
            app.GreenPiece2.ImageClickedFcn = createCallbackFcn(app, @GreenPiece2ImageClicked, true);
            app.GreenPiece2.ImageSource = 'player_green.png';
            app.GreenPiece3 = uiimage(app.UIFigure);
            app.GreenPiece3.ImageClickedFcn = createCallbackFcn(app, @GreenPiece3ImageClicked, true);
            app.GreenPiece3.ImageSource = 'player_green.png';
            app.RedPiece2 = uiimage(app.UIFigure);
            app.RedPiece2.ImageClickedFcn = createCallbackFcn(app, @RedPiece2ImageClicked, true);
            app.RedPiece2.ImageSource = 'player_red.png';
            app.RedPiece3 = uiimage(app.UIFigure);
            app.RedPiece3.ImageClickedFcn = createCallbackFcn(app, @RedPiece3ImageClicked, true);
            app.RedPiece3.ImageSource = 'player_red.png';
            app.BluePiece1 = uiimage(app.UIFigure);
            app.BluePiece1.ImageClickedFcn = createCallbackFcn(app, @BluePiece1ImageClicked, true);
            app.BluePiece1.ImageSource = 'player_blue.png';
            app.YellowPiece3 = uiimage(app.UIFigure);
            app.YellowPiece3.ImageClickedFcn = createCallbackFcn(app, @YellowPiece3ImageClicked, true);
            app.YellowPiece3.ImageSource = 'player_yellow.png';
            app.Dice3 = uiimage(app.UIFigure);
            app.Dice3.ImageSource = 'dice.3.jpg';
            app.Dice1 = uiimage(app.UIFigure);
            app.Dice1.ImageSource = 'dice.1.jpg';
            app.Dice1_2 = uiimage(app.UIFigure);
            app.Dice1_2.ImageSource = 'dice.1.jpg';
            app.Dice2_2 = uiimage(app.UIFigure);
            app.Dice2_2.ImageSource = 'dice.2_1.jpg';
            app.Dice3_2 = uiimage(app.UIFigure);
            app.Dice3_2.ImageSource = 'dice.3.jpg';
            app.Dice4_2 = uiimage(app.UIFigure);
            app.Dice4_2.ImageSource = 'dice.4.jpg';
            app.GreenPiece1 = uiimage(app.UIFigure);
            app.GreenPiece1.ImageClickedFcn = createCallbackFcn(app, @GreenPiece1ImageClicked, true);
            app.GreenPiece1.ImageSource = 'player_green.png';
            app.RedPiece1 = uiimage(app.UIFigure);
            app.RedPiece1.ImageClickedFcn = createCallbackFcn(app, @RedPiece1ImageClicked, true);
            app.RedPiece1.ImageSource = 'player_red.png';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end