classdef Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image_2                    matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        SORRYLabel                 matlab.ui.control.Label
        DescriptionButton          matlab.ui.control.Button
        RedPlayerNameEditField     matlab.ui.control.EditField
        GreenPlayerNameEditField   matlab.ui.control.EditField
        YellowPlayerNameEditField  matlab.ui.control.EditField
        BluePlayerNameEditField    matlab.ui.control.EditField
        PlayButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Player names
        BluePlayerName;
        YellowPlayerName;
        GreenPlayerName;
        RedPlayerName;
    end
    
    methods (Access = private)
        
        function set_names (app)
            % Sets player names to the values entered at the start
            app.BluePlayerName = app.BluePlayerNameEditField.Value;
            app.YellowPlayerName = app.YellowPlayerNameEditField.Value;
            app.GreenPlayerName = app.GreenPlayerNameEditField.Value;
            app.RedPlayerName = app.RedPlayerNameEditField.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.PlayButton.Enable = 'off';
            
            set_names(app);
            
            % Disables everything
            app.BluePlayerNameEditField.Enable = 'off';
            app.YellowPlayerNameEditField.Enable = 'off';
            app.GreenPlayerNameEditField.Enable = 'off';
            app.RedPlayerNameEditField.Enable = 'off';
            app.DescriptionButton.Enable = 'off';
            
            Game(app)  % Opens Game.mlapp
        end

        % Button pushed function: DescriptionButton
        function DescriptionButtonPushed(app, event)
            GameDescription()      % Opens GameDescription.mlapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.BluePlayerNameEditField = uieditfield(app.UIFigure, 'text');
            app.BluePlayerNameEditField.Value = 'Blue Player Name';
            app.YellowPlayerNameEditField = uieditfield(app.UIFigure, 'text');
            app.YellowPlayerNameEditField.Value = 'Yellow Player Name';
            app.GreenPlayerNameEditField = uieditfield(app.UIFigure, 'text');
            app.GreenPlayerNameEditField.Value = 'Green Player Name';
            app.RedPlayerNameEditField = uieditfield(app.UIFigure, 'text');
            app.RedPlayerNameEditField.Value = 'Red Player Name';
            app.DescriptionButton = uibutton(app.UIFigure, 'push');
            app.DescriptionButton.ButtonPushedFcn = createCallbackFcn(app, @DescriptionButtonPushed, true);
            app.DescriptionButton.Text = 'Description';
            app.SORRYLabel = uilabel(app.UIFigure);
            app.SORRYLabel.Text = 'SORRY';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'player_green.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'player_red.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end