classdef WinningScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EVERYONELOSESLabel  matlab.ui.control.Label
        PlayerNameLabel     matlab.ui.control.Label
        WinnerLabel         matlab.ui.control.Label
        confetti            matlab.ui.control.Image
    end

    
    properties (Access = private)
        GameApp % Holds the properties in Game.mlapp
    end
    
    methods (Access = private)
        
        function play_confetti_animation (app)
            for i = 1 : 5 
               atStart = app.confetti.Position == [-63, 480, 768, 320];
               if atStart == true
                destination = [-63, -318, 768, 320];
                coordinates = linspace(app.confetti.Position(2), destination(2), 200);
                for j = 1:length(coordinates)
                    app.confetti.Position(2) = coordinates(j);
                    pause(0.01);              
                end
                app.confetti.Position = [-63, 480, 768, 320];
             
                end
            end
            close(app.GameApp.StartApp.UIFigure)
            close(app.GameApp.UIFigure)
            close(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.GameApp = CallingApp;
            app.EVERYONELOSESLabel.Visible = 'off';
            
            % If player successfully hit the Critical Stage switch
            if strcmp(app.GameApp.Switch.Value, 'On')   
                app.PlayerNameLabel.Visible = 'off';
                app.WinnerLabel.Visible = 'off';
                app.EVERYONELOSESLabel.Visible = 'on';
            else
                % Checks which player won
                if app.GameApp.PiecesReachedHome(1) == 3
                    winner = app.GameApp.StartApp.BluePlayerName;
                elseif app.GameApp.PiecesReachedHome(2) == 3
                    winner = app.GameApp.StartApp.YellowPlayerName;
                elseif app.GameApp.PiecesReachedHome(3) == 3
                    winner = app.GameApp.StartApp.GreenPlayerName;
                else
                    winner = app.GameApp.StartApp.RedPlayerName;
                end
                               
                % Sets label to the winner's name
                app.PlayerNameLabel.Text = winner;
                play_confetti_animation(app);
            end                                             
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.confetti = uiimage(app.UIFigure);
            app.confetti.ImageSource = 'confetti.png';
            app.WinnerLabel = uilabel(app.UIFigure);
            app.WinnerLabel.Text = 'THE WINNER IS:';
            app.PlayerNameLabel = uilabel(app.UIFigure);
            app.PlayerNameLabel.Text = 'Player Name';
            app.EVERYONELOSESLabel = uilabel(app.UIFigure);
            app.EVERYONELOSESLabel.Enable = 'off';
            app.EVERYONELOSESLabel.Visible = 'off';
            app.EVERYONELOSESLabel.Text = 'EVERYONE LOSES!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WinningScreen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end